classdef CK_Ten < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        DrawButton                     matlab.ui.control.Button
        SlnlpEditFieldLabel            matlab.ui.control.Label
        SlnlpEditField                 matlab.ui.control.NumericEditField
        ChnphngphptmChiailpNewtontiptuynEditFieldLabel  matlab.ui.control.Label
        ChnphngphptmChiailpNewtontiptuynEditField  matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        NhpphngtrnhEditField           matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhpkhongphnlynghimEditField    matlab.ui.control.EditField
        NhpsaischophpEditField_3Label  matlab.ui.control.Label
        NhpsaischophpEditField         matlab.ui.control.EditField
        KtqunghimEditField_3Label      matlab.ui.control.Label
        KtqunghimEditField             matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        DrawButton_2                   matlab.ui.control.Button
        ChnphngphpnisuyNisuyNewtonLagrangeLabel  matlab.ui.control.Label
        ChnphngphpnisuyNisuyNewtonLagrangeEditField  matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
        NhpdliuxEditField              matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        NhpdliuyEditField              matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel   matlab.ui.control.Label
        NhpgitrcnnisuyEditField        matlab.ui.control.EditField
        KtqunisuyEditField_3Label      matlab.ui.control.Label
        KtqunisuyEditField             matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        KtquathcnisuyEditField         matlab.ui.control.EditField
        UIAxes_2                       matlab.ui.control.UIAxes
        HiquyTab                       matlab.ui.container.Tab
        ohmTab                         matlab.ui.container.Tab
        TchphnTab                      matlab.ui.container.Tab
        GiithiuNhmTab                  matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            % Đọc hàm số từ trường nhập liệu
            funcStr = app.NhpphngtrnhEditField.Value;
            syms x;
            try
                f = str2func(['@(x)', funcStr]);  % Chuyển chuỗi thành hàm ẩn danh
                df = matlabFunction(diff(f(x), x));  % Tính đạo hàm
            catch
                uialert(app.UIFigure, 'Hàm số nhập không hợp lệ hoặc không thể tính đạo hàm.', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc khoảng x từ trường nhập liệu
            rangeStr = app.NhpkhongphnlynghimEditField.Value;
            rangeStr = strrep(rangeStr, ' ', '');  % Xóa khoảng trắng
            range = str2num(rangeStr);  % Chuyển chuỗi thành vector số
            if isempty(range) || length(range) ~= 2
                uialert(app.UIFigure, 'Nhập khoảng x không đúng. Nhập lại dạng "a,b".', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc sai số cho phép từ trường nhập liệu
            toleranceStr = app.NhpsaischophpEditField.Value;
            tolerance = str2double(toleranceStr);
            if isnan(tolerance) || tolerance <= 0
                uialert(app.UIFigure, 'Nhập sai số cho phép không hợp lệ. Sai số phải là một số dương.', 'Lỗi nhập liệu');
                return;
            end
            
            % Đọc phương pháp tìm nghiệm từ dropdown
            method = app.ChnphngphptmChiailpNewtontiptuynEditField.Value;
            
            % Kiểm tra nhập liệu và vẽ đồ thị
            fplot(app.UIAxes, f, range);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, 'Đồ thị hàm số');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            
            % Tính toán nghiệm và số lần lặp tùy theo phương pháp
            try
                switch method
                    case 'Chia đôi'
                        [root, iterations] = chiadoi(f, range(1), range(2), tolerance);
                    case 'Lặp'
                        initialGuess = (range(1) + range(2)) / 2;
                        fp = @(x) x - f(x) / (df(x) + eps);  % Sử dụng đạo hàm đã tính
                        [root, iterations] = lap(fp, initialGuess, tolerance);
                    case 'Newton'
                        initialGuess = (range(1) + range(2)) / 2;
                        [root, iterations] = tieptuyen(f, initialGuess, 0, tolerance);
                    otherwise
                        uialert(app.UIFigure, 'Phương pháp không được hỗ trợ.', 'Lỗi');
                        return;
                end
            catch ME
                uialert(app.UIFigure, ['Có lỗi xảy ra: ', ME.message], 'Lỗi tính toán');
                return;
            end
            
            % Hiển thị kết quả nghiệm và số lần lặp
            app.KtqunghimEditField.Value = num2str(root);
            app.SlnlpEditField.Value = iterations;  % Đảm bảo rằng số lần lặp là một chuỗi


        end

        % Button pushed function: DrawButton_2
        function DrawButton_2Pushed(app, event)
            xa = str2num(app.NhpdliuxEditField.Value);  % Chuyển chuỗi thành mảng số của x
            ya = str2num(app.NhpdliuyEditField.Value);  % Chuyển chuỗi thành mảng số của y
        
            if isempty(xa) || isempty(ya)
                uialert(app.UIFigure, 'Vui lòng nhập đầy đủ dữ liệu x và y.', 'Dữ liệu thiếu');
                return;
            end
        
            giaTriXCanNoiSuy = str2double(app.NhpgitrcnnisuyEditField.Value);
            if isnan(giaTriXCanNoiSuy)
                uialert(app.UIFigure, 'Vui lòng nhập một giá trị x hợp lệ để nội suy.', 'Giá trị không hợp lệ');
                return;
            end
        
            phuongPhapNoiSuy = app.ChnphngphpnisuyNisuyNewtonLagrangeEditField.Value;
            switch phuongPhapNoiSuy
                case 'Newton'
                    symbolicResult = NewtonInterpolation(xa, ya, giaTriXCanNoiSuy);  % Sử dụng giaTriXCanNoiSuy
                case 'Lagrange'
                    symbolicResult = Lagrange(xa, ya, giaTriXCanNoiSuy);  % Tương tự
                otherwise
                    uialert(app.UIFigure, 'Phương pháp nội suy không được hỗ trợ.', 'Error');
                    return;
            end
        
            % Hiển thị đa thức nội suy trong trường văn bản
            app.KtquathcnisuyEditField.Value = char(vpa(symbolicResult, 5));  % Làm tròn đến 5 chữ số
        
            % Đánh giá đa thức tại giá trị x cụ thể và hiển thị kết quả nội suy
            giaTriNoiSuy = double(subs(symbolicResult, giaTriXCanNoiSuy));
            app.KtqunisuyEditField.Value = num2str(giaTriNoiSuy, '%.5g');  % Hiển thị kết quả nội suy tại giá trị x
        
            % Vẽ đồ thị đa thức nội suy và các điểm dữ liệu
            fplot(app.UIAxes, symbolicResult, [min(xa), max(xa)]);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, xa, ya, 'ro');  % 'ro' để hiển thị các điểm dữ liệu là các dấu đỏ
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Đồ thị hàm số nội suy và dữ liệu thực');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            legend(app.UIAxes, 'Đa thức nội suy', 'Dữ liệu thực');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.DrawButton = uibutton(app.NghimTab, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphptmChiailpNewtontiptuynEditFieldLabel = uilabel(app.NghimTab);
            app.ChnphngphptmChiailpNewtontiptuynEditFieldLabel.Text = {'Chọn phương pháp tìm: '; 'Chia đôi, lặp, Newton'; '(tiếp tuyến)'};
            app.ChnphngphptmChiailpNewtontiptuynEditField = uieditfield(app.NghimTab, 'text');
            app.ChnphngphptmChiailpNewtontiptuynEditField.Value = 'Lặp';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpphngtrnhEditField.Value = 'x-sin(x)-0.25';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = {'Nhập khoảng phân ly'; ' nghiệm'};
            app.NhpkhongphnlynghimEditField = uieditfield(app.NghimTab, 'text');
            app.NhpkhongphnlynghimEditField.Value = '-1,2';
            app.NhpsaischophpEditField_3Label = uilabel(app.NghimTab);
            app.NhpsaischophpEditField_3Label.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'text');
            app.NhpsaischophpEditField.Value = '0.005';
            app.KtqunghimEditField_3Label = uilabel(app.NghimTab);
            app.KtqunghimEditField_3Label.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'text');
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.DrawButton_2 = uibutton(app.NisuyTab, 'push');
            app.DrawButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_2Pushed, true);
            app.DrawButton_2.Text = 'Draw';
            app.ChnphngphpnisuyNisuyNewtonLagrangeLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyNisuyNewtonLagrangeLabel.Text = {'Chọn phương pháp'; ' nội suy: '; 'Nội suy Newton, Lagrange'};
            app.ChnphngphpnisuyNisuyNewtonLagrangeEditField = uieditfield(app.NisuyTab, 'text');
            app.ChnphngphpnisuyNisuyNewtonLagrangeEditField.Value = 'Newton';
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuxEditField.Value = '[0.1, 0.2, 0.3, 0.4]';
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpdliuyEditField.Value = '[0.09983, 0.19867, 0.29552, 0.38942]';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.NhpgitrcnnisuyEditField.Value = '0.25';
            app.KtqunisuyEditField_3Label = uilabel(app.NisuyTab);
            app.KtqunisuyEditField_3Label.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            app.UIAxes_2 = uiaxes(app.NisuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.GiithiuNhmTab = uitab(app.TabGroup);
            app.GiithiuNhmTab.Title = 'Giới thiệu Nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CK_Ten

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end